classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        UITable                     matlab.ui.control.Table
        G_AlghoritmButton           matlab.ui.control.Button
        GPP_AlghoritmButton         matlab.ui.control.Button
        GPC_AlghoritmButton         matlab.ui.control.Button
        KaczmarzButton              matlab.ui.control.Button
        UITable2                    matlab.ui.control.Table
        DimAEditFieldLabel          matlab.ui.control.Label
        DimAEditField               matlab.ui.control.NumericEditField
        SalveazamodificariAButton   matlab.ui.control.Button
        UITable3                    matlab.ui.control.Table
        OptiuneEditFieldLabel       matlab.ui.control.Label
        OptiuneEditField            matlab.ui.control.NumericEditField
        rezolvaresistem1evolutiatimpilorLabel  matlab.ui.control.Label
        GeneraresistemprinrularefisiermButton  matlab.ui.control.Button
        GeneraresistemrandomButton  matlab.ui.control.Button
        GraficeBigDataButton        matlab.ui.control.Button
    end

    
    properties (Access = public)
        data % Description
    end
    

    methods (Access = private)

        % Callback function
        function AmplitudeSliderValueChanged(app, event)

      
        end

        % Callback function
        function ALGORITMGMenuSelected(app, event)
            
        end

        % Callback function
        function ALGORITMGPPMenuSelected(app, event)
            
        end

        % Callback function
        function ALGORITMGPCMenuSelected(app, event)
            
        end

        % Callback function
        function ALGORITMKaczmarzMenuSelected(app, event)
       
        end

        % Button pushed function: G_AlghoritmButton
        function G_AlghoritmButtonPushed(app, event)
            run('S_REZ_G.m');
            if(app.OptiuneEditField.Value == 1)
            app.data = load('incercare.mat');
            app.UITable2.Data = app.data.A;
            imshow('Andu.png','Parent',app.UIAxes);
            end
            if (app.OptiuneEditField.Value == 2)
            app.data = load('solutie_G.mat');
            app.UITable.Data = app.data.x;
            app.UITable3.Data = app.data.b;
            app.UITable2.Data = app.data.A;
            end
        end

        % Button pushed function: GPP_AlghoritmButton
        function GPP_AlghoritmButtonPushed(app, event)
            run('GPP.m');
            if(app.OptiuneEditField.Value == 1)
            app.data = load('graficeGPP.mat');
            app.UITable2.Data = app.data.A;
            imshow('Andu.png','Parent',app.UIAxes);
            end
            if (app.OptiuneEditField.Value == 2)
            app.data = load('incercare_GPP.mat');
            app.UITable.Data = app.data.x;
            app.UITable3.Data = app.data.b;
            app.UITable2.Data = app.data.A;
            end

        end

        % Button pushed function: GPC_AlghoritmButton
        function GPC_AlghoritmButtonPushed(app, event)
            run('alg_GPC.m');
            if(app.OptiuneEditField.Value == 1)
            app.data = load('incercare_GPC.mat');
            app.UITable2.Data = app.data.U;
            imshow('Andu.png','Parent',app.UIAxes);
            end
            if (app.OptiuneEditField.Value == 2)
            app.data = load('solutie_GPC.mat');
            app.UITable.Data = app.data.x;
            app.UITable3.Data = app.data.b;
            app.UITable2.Data = app.data.A;
            end

        end

        % Button pushed function: KaczmarzButton
        function KaczmarzButtonPushed(app, event)
            run('Kacsmarz.m');
            if(app.OptiuneEditField.Value == 1)
            imshow('Andu.png','Parent',app.UIAxes);
            app.data = load('Kaczmarz_grafice.mat');
            app.UITable2.Data = app.data.A;
            app.UITable.Data = app.data.x;
            app.UITable3.Data = app.data.b;
            end
            if (app.OptiuneEditField.Value == 2)
            app.data = load('Kaczmarz.mat');
            app.UITable.Data = app.data.x;
            app.UITable3.Data = app.data.b;
            app.UITable2.Data = app.data.A;
            end
        end

        % Cell edit callback: UITable2
        function UITable2CellEdit(app, event)
            %indices = event.Indices;
            %newData = event.NewData;

        end

        % Value changed function: DimAEditField
        function EditFieldValueChanged(app, event)
            
            %A data
 
            value = app.DimAEditField.Value;
            %b data
            b = zeros(value,1);
            app.UITable3.Data = b;
            app.UITable3.ColumnEditable = true(1,1);
                                   
            A = zeros(value,value);
            app.UITable2.Data = A;
            ncols = size(app.UITable2.Data,2);
            app.UITable2.ColumnEditable = true(1,value); 
        end

        % Button pushed function: SalveazamodificariAButton
        function SalveazamodificariAButtonPushed(app, event)

            optiune = app.OptiuneEditField.Value;
            n = app.DimAEditField.Value;
            save('input.mat','n','optiune');
        end

        % Cell edit callback: UITable3
        function UITable3CellEdit(app, event)
            %indices = event.Indices;
            %newData = event.NewData;
            
        end

        % Cell selection callback: UITable2
        function UITable2CellSelection(app, event)
            %indices = event.Indices;
            
        end

        % Value changed function: OptiuneEditField
        function OptiuneEditFieldValueChanged(app, event)
           value = app.OptiuneEditField.Value;
        end

        % Button pushed function: GeneraresistemrandomButton
        function GeneraresistemrandomButtonPushed(app, event)
            dorinta = 3;
            save('input1.mat','dorinta');
        end

        % Button pushed function: GraficeBigDataButton
        function GraficeBigDataButtonPushed(app, event)
            dorinta = 5;
            save('input1.mat','dorinta');
            run('main.m');
            imshow('Andu.png','Parent',app.UIAxes);
            %%aici trebuie testat de cineva care are rabdare
        end

        % Button pushed function: 
        % GeneraresistemprinrularefisiermButton
        function GeneraresistemprinrularefisiermButtonPushed(app, event)
            dorinta = 4;
            save('input1.mat','dorinta');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.G_AlghoritmButton = uibutton(app.UIFigure, 'push');
            app.G_AlghoritmButton.ButtonPushedFcn = createCallbackFcn(app, @G_AlghoritmButtonPushed, true);
            app.G_AlghoritmButton.Text = 'G_Alghoritm';
            app.GPP_AlghoritmButton = uibutton(app.UIFigure, 'push');
            app.GPP_AlghoritmButton.ButtonPushedFcn = createCallbackFcn(app, @GPP_AlghoritmButtonPushed, true);
            app.GPP_AlghoritmButton.Text = 'GPP_Alghoritm';
            app.GPC_AlghoritmButton = uibutton(app.UIFigure, 'push');
            app.GPC_AlghoritmButton.ButtonPushedFcn = createCallbackFcn(app, @GPC_AlghoritmButtonPushed, true);
            app.GPC_AlghoritmButton.Text = 'GPC_Alghoritm';
            app.KaczmarzButton = uibutton(app.UIFigure, 'push');
            app.KaczmarzButton.ButtonPushedFcn = createCallbackFcn(app, @KaczmarzButtonPushed, true);
            app.KaczmarzButton.Text = 'Kaczmarz';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.UITable2.CellEditCallback = createCallbackFcn(app, @UITable2CellEdit, true);
            app.UITable2.CellSelectionCallback = createCallbackFcn(app, @UITable2CellSelection, true);
            app.DimAEditFieldLabel = uilabel(app.UIFigure);
            app.DimAEditFieldLabel.Text = 'Dim A';
            app.DimAEditField = uieditfield(app.UIFigure, 'numeric');
            app.DimAEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.SalveazamodificariAButton = uibutton(app.UIFigure, 'push');
            app.SalveazamodificariAButton.ButtonPushedFcn = createCallbackFcn(app, @SalveazamodificariAButtonPushed, true);
            app.SalveazamodificariAButton.Text = 'Salveaza modificari A';
            app.UITable3 = uitable(app.UIFigure);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.RowName = {};
            app.UITable3.CellEditCallback = createCallbackFcn(app, @UITable3CellEdit, true);
            app.OptiuneEditFieldLabel = uilabel(app.UIFigure);
            app.OptiuneEditFieldLabel.Text = 'Optiune';
            app.OptiuneEditField = uieditfield(app.UIFigure, 'numeric');
            app.OptiuneEditField.ValueChangedFcn = createCallbackFcn(app, @OptiuneEditFieldValueChanged, true);
            app.rezolvaresistem1evolutiatimpilorLabel = uilabel(app.UIFigure);
            app.rezolvaresistem1evolutiatimpilorLabel.Text = {'2--rezolvare sistem '; '1-- evolutia timpilor'};
            app.GeneraresistemprinrularefisiermButton = uibutton(app.UIFigure, 'push');
            app.GeneraresistemprinrularefisiermButton.ButtonPushedFcn = createCallbackFcn(app, @GeneraresistemprinrularefisiermButtonPushed, true);
            app.GeneraresistemprinrularefisiermButton.Text = 'Generare sistem prin rulare fisier .m';
            app.GeneraresistemrandomButton = uibutton(app.UIFigure, 'push');
            app.GeneraresistemrandomButton.ButtonPushedFcn = createCallbackFcn(app, @GeneraresistemrandomButtonPushed, true);
            app.GeneraresistemrandomButton.Text = {''; 'Generare sistem random'};
            app.GraficeBigDataButton = uibutton(app.UIFigure, 'push');
            app.GraficeBigDataButton.ButtonPushedFcn = createCallbackFcn(app, @GraficeBigDataButtonPushed, true);
            app.GraficeBigDataButton.Text = 'Grafice Big Data';
        end
    end

    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end